classdef ModelSelection_S < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ParameterLabel_3           matlab.ui.control.Label
        ParameterLabel_2           matlab.ui.control.Label
        ParameterLabel_1           matlab.ui.control.Label
        ParameterEditField_1       matlab.ui.control.NumericEditField
        ParameterEditField_2       matlab.ui.control.NumericEditField
        ParameterEditField_3       matlab.ui.control.NumericEditField
        HyperparametersLabel       matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        ModelLabel                 matlab.ui.control.Label
        AddToModelSelectionButton  matlab.ui.control.Button
        ModelDropDown              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        callingApp % The app that called this app
    end
    
    methods (Access = private)
        function resetHyperparameters(app)
            app.ParameterEditField_1.Visible = 'off';
            app.ParameterEditField_1.Value = 0;
            app.ParameterLabel_1.Visible = 'off';
            app.ParameterLabel_1.Text = '';
            app.ParameterEditField_2.Visible = 'off';
            app.ParameterEditField_2.Value = 0;
            app.ParameterLabel_2.Visible = 'off';
            app.ParameterLabel_2.Text = '';
            app.ParameterEditField_3.Visible = 'off';
            app.ParameterEditField_3.Value = 0;
            app.ParameterLabel_3.Visible = 'off';
            app.ParameterLabel_3.Text = '';
        end

        function showHyperparametersFor(app, model)
            switch model
                case 'Grubs test'
                    app.ParameterEditField_1.Visible = 'on';
                    app.ParameterEditField_1.Value = 0.5;
                    app.ParameterLabel_1.Visible = 'on';
                    app.ParameterLabel_1.Text = 'alpha';
                case 'OD_wpca'
                    app.ParameterEditField_1.Value = 0.1;
                    app.ParameterEditField_1.Visible = 'on';
                    app.ParameterLabel_1.Text = 'ratioOversample';
                    app.ParameterLabel_1.Visible = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;
        end

        % Button pushed function: AddToModelSelectionButton
        function AddToModelSelectionButtonPushed(app, event)
            options.type = 'S';
            model = app.ModelDropDown.Value;
            options.model = model;

            switch model
                case 'Grubbs test'
                    options.hyperparameters.model.alpha.value = app.ParameterEditField_1.Value;
                    options.hyperparameters.model.alpha.type = 'real';
                case 'OD_wpca'
                    options.hyperparameters.model.ratioOversample.value = app.ParameterEditField_1.Value;
                    options.hyperparameters.model.ratioOversample.type = 'real';
            end
            app.callingApp.addModel_S(options);
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            app.resetHyperparameters();
            app.showHyperparametersFor(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ModelDropDown = uidropdown(app.Panel);
            app.ModelDropDown.Items = {'Grubbs test', 'OD_wpca'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Grubbs test';
            app.AddToModelSelectionButton = uibutton(app.Panel, 'push');
            app.AddToModelSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @AddToModelSelectionButtonPushed, true);
            app.AddToModelSelectionButton.Text = 'Add to Model Selection';
            app.ModelLabel = uilabel(app.Panel);
            app.ModelLabel.Text = 'Model:';
            app.HyperparametersLabel = uilabel(app.UIFigure);
            app.HyperparametersLabel.Text = 'Hyperparameters:';
            app.ParameterEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ParameterEditField_3.Visible = 'off';
            app.ParameterEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ParameterEditField_2.Visible = 'off';
            app.ParameterEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.ParameterEditField_1.Value = 0.5;
            app.ParameterLabel_1 = uilabel(app.UIFigure);
            app.ParameterLabel_1.Text = 'alpha';
            app.ParameterLabel_2 = uilabel(app.UIFigure);
            app.ParameterLabel_2.Visible = 'off';
            app.ParameterLabel_2.Text = '';
            app.ParameterLabel_3 = uilabel(app.UIFigure);
            app.ParameterLabel_3.Visible = 'off';
            app.ParameterLabel_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection_S(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end