classdef ModelSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TSADModelSelectionUIFigure  matlab.ui.Figure
        ModelLabel                  matlab.ui.control.Label
        ModelDropDown               matlab.ui.control.DropDown
        CloseButton                 matlab.ui.control.Button
        AddButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        % The app that called this app
        callingApp

        % Struct contatining models and their configurable parameters
        modelsConfig

        % Custom components which are created by code
        customComponents_modelInfo
        customComponents_hyperparameters
    end
    
    methods (Access = private)
        function initModelDropDown(app)
            modelClasses = fieldnames(app.modelsConfig);
            modelNames = cell(1, numel(modelClasses));

            for i = 1:numel(modelClasses)
                modelNames{i} = app.modelsConfig.(modelClasses{i}).name;
            end

            app.ModelDropDown.Items = modelNames;
            app.ModelDropDown.ItemsData = modelClasses;
        end

        function showSelectedModelUI(app)
            componentCounter = 0;

            selectedModel = app.ModelDropDown.Value;
            configurables = app.modelsConfig.(selectedModel).configurables;

            if isfield(configurables, "modelInfo")
                modelInfo = configurables.modelInfo;
            else
                modelInfo = [];
            end

            if isfield(configurables, "hyperparameters")
                hyperparameters = configurables.hyperparameters;
            else
                hyperparameters = [];
            end
            
            if ~isempty(modelInfo)
                paramFields = fieldnames(modelInfo);

                for i = 1:numel(paramFields)
                    paramName = paramFields{i};
                    paramValue = modelInfo.(paramName);               
                    app.addModelInfoComponent(paramName, paramValue, componentCounter);
                    componentCounter = componentCounter + 1;
                end
            end

            if ~isempty(hyperparameters)
                paramFields = fieldnames(hyperparameters);

                for i = 1:numel(paramFields)
                    paramName = paramFields{i};
                    paramValue = hyperparameters.(paramName);               
                    app.addHyperparameterComponent(paramName, paramValue, componentCounter);
                    componentCounter = componentCounter + 1;
                end
            end
        end

        function addModelInfoComponent(app, paramName, paramValue, componentCounter)
            componentNamePosition = [20, 630-30*(componentCounter+1), 190, 22];
            componentPosition = [220, 630-30*(componentCounter+1), 360, 22];
    
            componentName = sprintf("Component_%d", componentCounter);
            
    
            switch paramValue.type
                case "categorical"
                    % Create Label
                    app.customComponents_modelInfo.(componentName).componentLabel = uilabel(app.TSADModelSelectionUIFigure);
                    app.customComponents_modelInfo.(componentName).componentLabel.Position = componentNamePosition;
                    app.customComponents_modelInfo.(componentName).componentLabel.Text = paramName;
    
                    % Create DropDown
                    app.customComponents_modelInfo.(componentName).component = uidropdown(app.TSADModelSelectionUIFigure);
                    app.customComponents_modelInfo.(componentName).component.Items = paramValue.value;
                    app.customComponents_modelInfo.(componentName).component.Position = componentPosition;
                    app.customComponents_modelInfo.(componentName).component.Value = paramValue.initialValue;
                case "integer"
                    % Create Label
                    app.customComponents_modelInfo.(componentName).componentLabel = uilabel(app.TSADModelSelectionUIFigure);
                    app.customComponents_modelInfo.(componentName).componentLabel.Position = componentNamePosition;
                    app.customComponents_modelInfo.(componentName).componentLabel.Text = paramName;
    
                    % Create EditField
                    app.customComponents_modelInfo.(componentName).component = uieditfield(app.TSADModelSelectionUIFigure, "numeric");
                    app.customComponents_modelInfo.(componentName).component.Limits = paramValue.value;
                    app.customComponents_modelInfo.(componentName).component.Position = componentPosition;
                    app.customComponents_modelInfo.(componentName).component.Value = paramValue.initialValue;
                    app.customComponents_modelInfo.(componentName).component.RoundFractionalValues = true;
                case "real"
                    % Create Label
                    app.customComponents_modelInfo.(componentName).componentLabel = uilabel(app.TSADModelSelectionUIFigure);
                    app.customComponents_modelInfo.(componentName).componentLabel.Position = componentNamePosition;
                    app.customComponents_modelInfo.(componentName).componentLabel.Text = paramName;
    
                    % Create EditField
                    app.customComponents_modelInfo.(componentName).component = uieditfield(app.TSADModelSelectionUIFigure, "numeric");
                    app.customComponents_modelInfo.(componentName).component.Limits = paramValue.value;
                    app.customComponents_modelInfo.(componentName).component.Position = componentPosition;
                    app.customComponents_modelInfo.(componentName).component.Value = paramValue.initialValue;
                    app.customComponents_modelInfo.(componentName).component.RoundFractionalValues = false;
            end 
        end

        function addHyperparameterComponent(app, paramName, paramValue, componentCounter)
            componentNamePosition = [20, 630-30*(componentCounter+1), 190, 22];
            componentPosition = [220, 630-30*(componentCounter+1), 360, 22];
    
            componentName = sprintf("Component_%d", componentCounter);
            
    
            switch paramValue.type
                case "categorical"
                    % Create Label
                    app.customComponents_hyperparameters.(componentName).componentLabel = uilabel(app.TSADModelSelectionUIFigure);
                    app.customComponents_hyperparameters.(componentName).componentLabel.Position = componentNamePosition;
                    app.customComponents_hyperparameters.(componentName).componentLabel.Text = paramName;
    
                    % Create DropDown
                    app.customComponents_hyperparameters.(componentName).component = uidropdown(app.TSADModelSelectionUIFigure);
                    app.customComponents_hyperparameters.(componentName).component.Items = paramValue.value;
                    app.customComponents_hyperparameters.(componentName).component.Position = componentPosition;
                    app.customComponents_hyperparameters.(componentName).component.Value = paramValue.initialValue;
                case "integer"
                    % Create Label
                    app.customComponents_hyperparameters.(componentName).componentLabel = uilabel(app.TSADModelSelectionUIFigure);
                    app.customComponents_hyperparameters.(componentName).componentLabel.Position = componentNamePosition;
                    app.customComponents_hyperparameters.(componentName).componentLabel.Text = paramName;
    
                    % Create EditField
                    app.customComponents_hyperparameters.(componentName).component = uieditfield(app.TSADModelSelectionUIFigure, "numeric");
                    app.customComponents_hyperparameters.(componentName).component.Limits = paramValue.value;
                    app.customComponents_hyperparameters.(componentName).component.Position = componentPosition;
                    app.customComponents_hyperparameters.(componentName).component.Value = paramValue.initialValue;
                    app.customComponents_hyperparameters.(componentName).component.RoundFractionalValues = true;
                case "real"
                    % Create Label
                    app.customComponents_hyperparameters.(componentName).componentLabel = uilabel(app.TSADModelSelectionUIFigure);
                    app.customComponents_hyperparameters.(componentName).componentLabel.Position = componentNamePosition;
                    app.customComponents_hyperparameters.(componentName).componentLabel.Text = paramName;
    
                    % Create EditField
                    app.customComponents_hyperparameters.(componentName).component = uieditfield(app.TSADModelSelectionUIFigure, "numeric");
                    app.customComponents_hyperparameters.(componentName).component.Limits = paramValue.value;
                    app.customComponents_hyperparameters.(componentName).component.Position = componentPosition;
                    app.customComponents_hyperparameters.(componentName).component.Value = paramValue.initialValue;
                    app.customComponents_hyperparameters.(componentName).component.RoundFractionalValues = false;
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callingApp = mainApp;

            % Load TSADConfig_models
            app.modelsConfig = readJSONToStruct("TSADConfig_models.json");

            app.initModelDropDown();
            app.showSelectedModelUI();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            modelInfo = struct;
            hyperparemters = struct;

            % Get model className
            className = app.ModelDropDown.Value;

            % Get model name
            name = app.modelsConfig.(className).name;
            
            % Get all modelInfo fields
            fields = fieldnames(app.customComponents_modelInfo);
            for i = 1:numel(fields)
                componentName = app.customComponents_modelInfo.(fields{i}).componentLabel.Text;
                modelInfo.(componentName) = app.customComponents_modelInfo.(fields{i}).component.Value;
            end
            modelInfo.className = className;

            
            % Get all hyperparameter fields
            fields = fieldnames(app.customComponents_hyperparameters);
            for i = 1:numel(fields)
                componentName = app.customComponents_hyperparameters.(fields{i}).componentLabel.Text;
                hyperparameters.(componentName) = app.customComponents_hyperparameters.(fields{i}).component.Value;
            end

            % Construct config
            config.modelInfo = modelInfo;
            config.hyperparameters = hyperparameters;


            app.callingApp.addModel(name, className, config);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TSADModelSelectionUIFigure = uifigure('Visible', 'off');
            app.TSADModelSelectionUIFigure.Name = 'TSAD - Model Selection';
            app.TSADModelSelectionUIFigure.Icon = fullfile(pathToMLAPP, 'media', 'TSADPlatform_Icon.png');
            app.AddButton = uibutton(app.TSADModelSelectionUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.CloseButton = uibutton(app.TSADModelSelectionUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ModelDropDown = uidropdown(app.TSADModelSelectionUIFigure);
            app.ModelDropDown.Items = {};
            app.ModelDropDown.Value = {};
            app.ModelLabel = uilabel(app.TSADModelSelectionUIFigure);
            app.ModelLabel.Text = 'Model';
            % Show the figure after all components are created
            app.TSADModelSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelSelection(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TSADModelSelectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TSADModelSelectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TSADModelSelectionUIFigure)
        end
    end
end